classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisizioneDatiTab            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        SelezioneGestoDropDown         matlab.ui.control.DropDown
        SelezioneGestoDropDownLabel    matlab.ui.control.Label
        SelezioneManoDropDown          matlab.ui.control.DropDown
        SelezioneManoDropDownLabel     matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel_2  matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel  matlab.ui.control.Label
        CampoobbligatorioLabel_2       matlab.ui.control.Label
        CampoobbligatorioLabel         matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        StopSaveButton                 matlab.ui.control.Button
        StartAcquisitionButton         matlab.ui.control.Button
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        SmartphoneModelEditField       matlab.ui.control.EditField
        SmartphoneModelEditFieldLabel  matlab.ui.control.Label
        PersonIDEditField              matlab.ui.control.EditField
        PersonIDEditFieldLabel         matlab.ui.control.Label
        VisualizzazioneDatiTab         matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        CambiaSelezioneButton          matlab.ui.control.Button
        VisualizationLabel             matlab.ui.control.Label
        CaricaFileButton               matlab.ui.control.Button
        GyroUIAxes                     matlab.ui.control.UIAxes
        OrientationUIAxes              matlab.ui.control.UIAxes
        AccUIAxes                      matlab.ui.control.UIAxes
        MagUIAxes                      matlab.ui.control.UIAxes
        EstrazioneFeatureTab           matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        SelezionailfilesignalsStructFilematLabel_2  matlab.ui.control.Label
        CaricaFileButton_2             matlab.ui.control.Button
        FeatureStatusTextArea          matlab.ui.control.TextArea
        FeatureStatusTextAreaLabel     matlab.ui.control.Label
        EstraiFeatureButton            matlab.ui.control.Button
        ClassificazioneGestiTab        matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        InserireunmodellopreaddestratoinformatomatLabel  matlab.ui.control.Label
        AcquisisciButton               matlab.ui.control.Button
        ClassificationStatusTextArea   matlab.ui.control.TextArea
        ClassificationStatusTextAreaLabel  matlab.ui.control.Label
        PreparaacquisizioneeclassificaButton  matlab.ui.control.Button
        CaricaModelloButton            matlab.ui.control.Button
    end

    properties (Access = private)
        smartphone                      % Oggetto mobiledev
        signalsStruct = struct()        % Struttura dati
        currentGesture = 1              % Indice gesto corrente
        isAcquiring = false             % Flag acquisizione
        currentRep = 1                  % Ripetizione corrente
        availableSensorsStr             % Sensori disponibili
        allGestures                     % Lista completa dei gesti
        acquisitionInterrupted = false  % Flag interruzione
        globalAcquisitionCounter = 1    % Contatore globale
        startFromGesture = 1            % Indice gesto iniziale
        waitForNext = false             % Next per bloccare le acquisizioni
        classificationModel             % Modello pre-addestrato per la classificazione
        waitForAcquisisci = false       % Blocca l'acquisizione nella parte di classificazione
    end
    
    methods (Access = private)

        function availableSensorsStr = getAvailableSensors(app)
            sensors = [];
            if app.smartphone.AccelerationSensorEnabled, sensors = [sensors, 1]; end
            if app.smartphone.AngularVelocitySensorEnabled, sensors = [sensors, 2]; end
            if app.smartphone.MagneticSensorEnabled, sensors = [sensors, 3]; end
            if app.smartphone.OrientationSensorEnabled, sensors = [sensors, 4]; end
    
            % Converti i numeri in cell array di caratteri
            sensorCells = arrayfun(@num2str, sensors, 'UniformOutput', false);
    
            % Unisci con il separatore '-'
            availableSensorsStr = strjoin(sensorCells, '-');
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
            {sprintf('Sensori disponibili: %s', availableSensorsStr)}];
        end
        
        function initializeAcquisition(app)
            % Elimina inizializzazione smartphone se già presente
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                app.smartphone.Logging = 0; % Si assicura che il logging sia off
                delete(app.smartphone);
                app.smartphone = [];
                clear('mobiledev');
            end

            % Inizializza dispositivo mobile
            app.smartphone = mobiledev;
            
            % Configurazione sensori
            app.smartphone.AccelerationSensorEnabled = 1;
            app.smartphone.MagneticSensorEnabled = 1;
            app.smartphone.OrientationSensorEnabled = 1;
            app.smartphone.AngularVelocitySensorEnabled = 1;
            app.smartphone.SampleRate = 100;
            
            % Carica/Crea struttura dati
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            else
                app.signalsStruct = struct();
            end
            
            % Inizializza lista gesti
            app.allGestures = [arrayfun(@(x) sprintf('Class %d', x), 0:23, 'UniformOutput', false), {'Class 24'}];
        end

        function startGestureAcquisition(app)
            % Resetta i contatori all'inizio
            app.globalAcquisitionCounter = 1;
            app.startFromGesture = find(strcmp(app.SelezioneGestoDropDown.Value, app.allGestures), 1);
            
            % Verifica esistenza soggetto nella struttura
            if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.signalsStruct.(app.PersonIDEditField.Value) = struct();
            end

            for gestureIdx = app.startFromGesture:length(app.allGestures)
                % Calcola l'indice progressivo corretto
                gestureID = app.allGestures{gestureIdx};
                baseIndex = (gestureIdx - app.startFromGesture) * 5;
                for rep = 1:5
                    if ~app.isAcquiring, return; end
                    
                    % Calcola nome acquisizione
                    acqNumber = baseIndex + rep;
                    acqName = sprintf('acquisition_%d', acqNumber);

                    % Aggiornamento UI
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {sprintf('Gesto: %s - Ripetizione: %d/5', gestureID, rep)}];
                    drawnow;
                    
                    % Attiva attesa
                    app.waitForNext = true;
                    app.NextButton.Enable = 'on';
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {'Premere "Next" per iniziare l''acquisizione'}];
                
                    % Attesa asincrona
                    while isvalid(app) && app.waitForNext && app.isAcquiring
                        pause(0.1);
                        drawnow;
                    end
                
                    if ~app.isAcquiring, return; end

                    % Acquisizione dati
                    app.acquireData(gestureIdx, rep);
                
                    if app.acquisitionInterrupted
                        return;
                    end

                end
                % Chiedi conferma per proseguire
                if gestureIdx ~= length(app.allGestures) || rep ~= 5
                    choice = questdlg('Continuare con il prossimo gesto?', ...
                        'Conferma', 'Si', 'No', 'Si');
                    if strcmp(choice, 'No')
                        app.saveData();
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                            {'Acquisizioni completate per questo gesto!'}];
                        app.isAcquiring = false;
                        return;
                    end
                end
            end
 
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                {'Acquisizioni completate per tutti i gesti!'}];
            app.isAcquiring = false;
        end

        function acquireData(app, gestureIdx, ~)
            try
                gestureID = app.allGestures{gestureIdx};
                % Avvia acquisizione
                app.smartphone.Logging = 1;
                pause(3.5); % Pausa acquisizione
                app.smartphone.Logging = 0;
                
                % Raccolta dati
                [acc, ~] = accellog(app.smartphone);
                [gyro, ~] = angvellog(app.smartphone);
                [mag, ~] = magfieldlog(app.smartphone);
                [orientation, ~] = orientlog(app.smartphone);
                app.smartphone.discardlogs;
                
                % Verifica dati
                if any([size(acc,1), size(gyro,1), size(mag,1), size(orientation,1)] < 250)
                    error('Acquisizione troppo corta!');
                end
                
                % Elaborazione dati
                acc = acc(1:250,:);
                gyro = gyro(1:250,:);
                mag = mag(1:250,:);
                orientation = orientation(1:250,:);
                
                % Salvataggio nella struttura
                acqName = sprintf('acquisition_%d', app.globalAcquisitionCounter);
                if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                    app.signalsStruct.(app.PersonIDEditField.Value) = struct();
                end
                
                app.signalsStruct.(app.PersonIDEditField.Value).(acqName) = struct(...
                    'acc', acc, ...
                    'gyro', gyro, ...
                    'mag', mag, ...
                    'orientation', orientation, ...
                    'GestureID', gestureID, ...
                    'Hand', app.SelezioneManoDropDown.Value, ...
                    'SmartphoneModel', app.SmartphoneModelEditField.Value, ...
                    'AvailableSensors', app.availableSensorsStr);
                
                % Incrementa contatore globale
                app.globalAcquisitionCounter = app.globalAcquisitionCounter + 1;
                
            catch ME
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {ME.message}];
                app.isAcquiring = false;
            end
        end

        function saveData(app)
            % Salva la struttura dati in un file MAT
            signalsStructFile = app.signalsStruct;
            save('signalsStructFile.mat', 'signalsStructFile');

            % Prepara la raccolta dei metadati in formato tabellare
            subjects = fieldnames(signalsStructFile);
    
            % Inizializza le variabili per ciascuna colonna
            ID_Subject       = {};
            Idx_Acquisition  = [];
            Hand             = {};
            SmartphoneModel  = {};
            AvailableSensors = {};
            ID_Gesture       = {};
    
            % Ciclo per ogni soggetto e per ciascuna acquisizione
            for i = 1:numel(subjects)
                person = subjects{i};
                acquisitions = fieldnames(signalsStructFile.(person));
                for j = 1:numel(acquisitions)
                    acq = signalsStructFile.(person).(acquisitions{j});
                    ID_Subject{end+1,1}       = person;
                    Idx_Acquisition(end+1,1)  = j;
                    Hand{end+1,1}             = acq.Hand;
                    SmartphoneModel{end+1,1}  = acq.SmartphoneModel;
                    AvailableSensors{end+1,1} = acq.AvailableSensors;
                    ID_Gesture{end+1,1}       = acq.GestureID;
                end
            end

            % Creazione della tabella dei metadati
            metadataTable = table(ID_Subject, Idx_Acquisition, Hand, SmartphoneModel, ...
                                AvailableSensors, ID_Gesture);
    
            % Salva la tabella in un file CSV SENZA la prima riga dei label
            writetable(metadataTable, 'Metadati.csv', 'WriteVariableNames', false);
    
            % Aggiorna lo status
            app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Dati salvati correttamente!'}];
        end

        function sensorData = acquireSensorData(app)
            % Elimina l'oggetto smartphone se esiste
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                try
                    app.smartphone.Logging = 0;
                    delete(app.smartphone);
                catch ME
                    error('Errore durante la disconnessione: , %s', ME.message);
                end
                app.smartphone = [];
            end
            
            % Inizializza il dispositivo mobile
            app.smartphone = mobiledev;
            if isempty(app.smartphone) || ~isvalid(app.smartphone)
                error('Dispositivo mobile non inizializzato!');
            end
            % Attivazione sensori
            app.smartphone.AccelerationSensorEnabled = 1;
            app.smartphone.AngularVelocitySensorEnabled = 1;
            app.smartphone.SampleRate = 100;
            
            % Attiva attesa
            app.waitForAcquisisci = true;
            app.AcquisisciButton.Enable = 'on';
            app.ClassificationStatusTextArea.Value = 'Premere "Acquisisci" per iniziare l''acquisizione';
             
            app.PreparaacquisizioneeclassificaButton.Enable = "off";

            % Attesa asincrona
            while app.waitForAcquisisci
                pause(0.1);
                drawnow;
            end

            % Avvio acquisizione dati
            app.smartphone.Logging = 1;
            pause(3.5);  % Durata dell'acquisizione
            app.smartphone.Logging = 0;
        
            % Raccolta dati dai sensori
            [acc, ~] = accellog(app.smartphone);
            [gyro, ~] = angvellog(app.smartphone);
            app.smartphone.discardlogs;
    
            % Verifica che il numero di campioni sia sufficiente (almeno 250 per ciascun segnale)
            if any([size(acc,1), size(gyro,1)] < 250)
                error('Acquisizione troppo corta!');
            end
    
            % Taglia a 250 campioni
            acc = acc(1:250,:);
            gyro = gyro(1:250,:);

            % Calcola tutte le feature necessarie
            features = struct();

            % Accelerometro
            features.AccMeanX = mean(acc(:,1));
            features.AccMeanY = mean(acc(:,2));
            features.AccMeanZ = mean(acc(:,3));
            features.AccStdX = std(acc(:,1));
            features.AccStdY = std(acc(:,2));
            features.AccStdZ = std(acc(:,3));
            features.AccEnergyX = sum(acc(:,1).^2)/250;
            features.AccEnergyY = sum(acc(:,2).^2)/250;
            features.AccEnergyZ = sum(acc(:,3).^2)/250;
    
            % Giroscopio
            features.GyroMeanX = mean(gyro(:,1));
            features.GyroMeanY = mean(gyro(:,2));
            features.GyroMeanZ = mean(gyro(:,3));
            features.GyroMaxX = max(abs(gyro(:,1)));
            features.GyroMaxY = max(abs(gyro(:,2)));
            features.GyroMaxZ = max(abs(gyro(:,3)));
    
            % Correlazioni
            features.CorrX = corr(acc(:,1), gyro(:,1));
            features.CorrY = corr(acc(:,2), gyro(:,2));
            features.CorrZ = corr(acc(:,3), gyro(:,3));
    
            sensorData = features;
        end

        function classifyGesture(app)
            % Verifica che il modello sia stato caricato
            if isempty(app.classificationModel)
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value;
                    {'Errore: carica un modello pre-addestrato prima!'}];
                return;
            end
    
            try
                % Acquisizione dati grezzi
                featureStruct = app.acquireSensorData();
        
                % Converti i dati in formato tabella (come richiesto da predictFcn)
                inputTable = struct2table(featureStruct);
        
                % Effettua la predizione usando predictFcn
                [yfit, scores] = app.classificationModel.predictFcn(inputTable);

                % Estrai il punteggio massimo
                [maxScore, ~] = max(scores);
               
                % Soglia Wide Neural Network (0.99 riferimento)
                %soglia = 0.99;
                
                % Soglia SWM (-0.3 riferimento)
                soglia = -0.3;

                if maxScore < soglia
                    gestureName = 'Gesto non riconosciuto';
                else
                    % Assegna i nomi ai gesti
                    switch yfit
                        case 0
                            gestureName = 'Gesto 0 - A';
                        case 1
                            gestureName = 'Gesto 1 - C';
                        case 2
                            gestureName = 'Gesto 2 - D';
                        case 3
                            gestureName = 'Gesto 3 - Classe di Scarto';
                        otherwise
                            gestureName = 'Gesto non riconosciuto'; 
                    end
                end
                % Mostra il risultato
                resultStr = sprintf('Risultato: %s (max score: %.3f)', gestureName, maxScore);
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {resultStr}];
                app.PreparaacquisizioneeclassificaButton.Enable = "on";

            catch ME
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {['Errore nella classificazione: ', ME.message]}];
            end
        end

        % Funzione che carica il file, permette la scelta interattiva di soggetto e classe
        % e aggiorna i grafici negli assi.
        function loadFile(app)
            % Seleziona il file MAT
            [file, path] = uigetfile('*.mat', 'Seleziona il file signalsStructFile');
            if isequal(file, 0)
                return;
            end
            filePath = fullfile(path, file);
            loadedData = load(filePath);
            if isfield(loadedData, 'signalsStructFile')
                app.signalsStruct = loadedData.signalsStructFile;
            else
                uialert(app.UIFigure, 'Il file non contiene la variabile signalsStructFile', 'Errore');
                return;
            end
            
            % Selezione dell'utente tramite listdlg
            subjects = fieldnames(app.signalsStruct);
            if isempty(subjects)
                uialert(app.UIFigure, 'Nessun soggetto trovato nel file.', 'Errore');
                return;
            end
            [sel, ok] = listdlg('ListString', subjects, ...
                                'SelectionMode', 'single', ...
                                'PromptString', 'Seleziona un soggetto:');
            if ~ok
                return;
            end
            selectedUser = subjects{sel};
            
            % Ottieni le acquisizioni dell'utente selezionato
            userAcquisitions = app.signalsStruct.(selectedUser);
            acqNames = fieldnames(userAcquisitions);
            if isempty(acqNames)
                uialert(app.UIFigure, 'Il soggetto selezionato non ha acquisizioni.', 'Errore');
                return;
            end
            
            % Estrai le classi (GestureID) presenti nelle acquisizioni
            gestureList = cell(size(acqNames));
            for i = 1:numel(acqNames)
                gestureList{i} = userAcquisitions.(acqNames{i}).GestureID;
            end
            uniqueGestures = unique(gestureList);
            
            % Selezione della classe tramite listdlg
            [sel, ok] = listdlg('ListString', uniqueGestures, ...
                                'SelectionMode', 'single', ...
                                'PromptString', 'Seleziona una classe di gesto:');
            if ~ok
                return;
            end
            selectedGesture = uniqueGestures{sel};
            
            % Aggiorna il label con l'utente e il gesto selezionati
            app.VisualizationLabel.Text = sprintf('Visualizzazione: %s - %s', selectedUser, selectedGesture);

            % Cerca la prima acquisizione che corrisponde alla classe selezionata
            found = false;
            for i = 1:numel(acqNames)
                data = userAcquisitions.(acqNames{i});
                if strcmp(data.GestureID, selectedGesture)
                    found = true;
                    break;
                end
            end
            if ~found
                uialert(app.UIFigure, 'Nessuna acquisizione trovata per questa classe', 'Errore');
                cla(app.AccUIAxes); cla(app.GyroUIAxes);
                cla(app.MagUIAxes); cla(app.OrientationUIAxes);
                return;
            end
            
            % Visualizza i grafici della prima acquisizione trovata
            % Si assume che i dati abbiano 250 campioni a 100 Hz
            time = (0:249) / 100;
            
            % Grafico Accelerometro
            plot(app.AccUIAxes, time, data.acc);
            title(app.AccUIAxes, 'Accelerometro');
            xlabel(app.AccUIAxes, 'Tempo [s]');
            ylabel(app.AccUIAxes, 'Acc [m/s^2]');
            legend(app.AccUIAxes, 'X','Y','Z');
            
            % Grafico Giroscopio
            plot(app.GyroUIAxes, time, data.gyro);
            title(app.GyroUIAxes, 'Giroscopio');
            xlabel(app.GyroUIAxes, 'Tempo [s]');
            ylabel(app.GyroUIAxes, 'Gyro [rad/s]');
            legend(app.GyroUIAxes, 'X','Y','Z');
            
            % Grafico Magnetometro
            plot(app.MagUIAxes, time, data.mag);
            title(app.MagUIAxes, 'Magnetometro');
            xlabel(app.MagUIAxes, 'Tempo [s]');
            ylabel(app.MagUIAxes, 'Mag [\muT]');
            legend(app.MagUIAxes, 'X','Y','Z');
            
            % Grafico Orientazione
            plot(app.OrientationUIAxes, time, data.orientation);
            title(app.OrientationUIAxes, 'Orientazione');
            xlabel(app.OrientationUIAxes, 'Tempo [s]');
            ylabel(app.OrientationUIAxes, 'Gradi [°]');
            legend(app.OrientationUIAxes, 'X','Y','Z');
        end

        function extractFeatures(app)
            % Controlla se il file è stato caricato
            if isempty(app.signalsStruct)
                uialert(app.UIFigure, 'Carica prima il file signalsStructFile.mat usando il pulsante "Carica SignalStruct"', 'Errore');
                return;
            end
            
            % Definisce i gesti target
            targetGestures = {'Class 0', 'Class 2', 'Class 3', 'Class 24'};
            
            % Definisce i nomi delle feature
            featureNames = {...
                'AccMeanX', 'AccMeanY', 'AccMeanZ', ...
                'AccStdX', 'AccStdY', 'AccStdZ', ...
                'AccEnergyX', 'AccEnergyY', 'AccEnergyZ', ...
                'GyroMeanX', 'GyroMeanY', 'GyroMeanZ', ...
                'GyroMaxX', 'GyroMaxY', 'GyroMaxZ', ...
                'CorrX', 'CorrY', 'CorrZ'};
            
            % Inizializza la matrice delle feature e il vettore delle etichette
            featureMatrix = [];
            labels = [];
            
            % Ottiene la lista dei soggetti
            subjects = fieldnames(app.signalsStruct);
            
            for subjIdx = 1:length(subjects)
                personID = subjects{subjIdx};
                acquisitions = fieldnames(app.signalsStruct.(personID));
                for acqIdx = 1:length(acquisitions)
                    acqName = acquisitions{acqIdx};
                    data = app.signalsStruct.(personID).(acqName);
                    
                    if ismember(data.GestureID, targetGestures)
                        featureVector = [];
                        % Accelerometro: media, std, energia (assumendo 250 campioni)
                        acc_mean = mean(data.acc);
                        acc_std = std(data.acc);
                        acc_energy = sum(data.acc.^2)/250;
                        featureVector = [featureVector, acc_mean, acc_std, acc_energy];
                        
                        % Giroscopio: media e max assoluto
                        gyro_mean = mean(data.gyro);
                        gyro_max = max(abs(data.gyro));
                        featureVector = [featureVector, gyro_mean, gyro_max];
                        
                        % Correlazioni per ogni asse
                        corrValues = zeros(1,3);
                        for axis = 1:3
                            corrValues(axis) = corr(data.acc(:,axis), data.gyro(:,axis));
                        end
                        featureVector = [featureVector, corrValues];
                        
                        featureMatrix = [featureMatrix; featureVector];
                        [~, labelIdx] = ismember(data.GestureID, targetGestures);
                        labels = [labels; labelIdx - 1];
                    end
                end
            end
            
            if size(featureMatrix, 2) ~= length(featureNames)
                uialert(app.UIFigure, sprintf('Il numero di colonne in featureMatrix (%d) non corrisponde al numero di nomi di feature (%d).', ...
                    size(featureMatrix,2), length(featureNames)), 'Errore');
                return;
            end
            
            featureTable = array2table(featureMatrix, 'VariableNames', featureNames);
            featureTable.Label = categorical(labels);
            
            save('GestureFeatures.mat', 'featureTable');
            writetable(featureTable, 'GestureFeatures.csv');
            
            app.FeatureStatusTextArea.Value = {...
                'Feature estratte correttamente!', ...
                'Ora puoi addestrare un modello con il Classification Learner'};
            app.EstraiFeatureButton.Enable = "off";
        end

        function changeUserGesture(app)
            if isempty(app.signalsStruct)
                uialert(app.UIFigure, 'Carica prima il file signalsStructFile.mat!', 'Errore');
                return;
            end
            
            % Selezione del soggetto
            subjects = fieldnames(app.signalsStruct);
            [sel, ok] = listdlg('ListString', subjects, ...
                                'SelectionMode', 'single', ...
                                'PromptString', 'Seleziona un soggetto:');
            if ~ok
                return;
            end
            selectedUser = subjects{sel};
            
            % Ottieni le acquisizioni per il soggetto scelto
            userAcquisitions = app.signalsStruct.(selectedUser);
            acqNames = fieldnames(userAcquisitions);
            if isempty(acqNames)
                uialert(app.UIFigure, 'Il soggetto selezionato non ha acquisizioni.', 'Errore');
                return;
            end
            
            % Selezione della classe di gesto
            gestureList = cell(size(acqNames));
            for i = 1:numel(acqNames)
                gestureList{i} = userAcquisitions.(acqNames{i}).GestureID;
            end
            uniqueGestures = unique(gestureList);
            [sel, ok] = listdlg('ListString', uniqueGestures, ...
                                'SelectionMode', 'single', ...
                                'PromptString', 'Seleziona una classe di gesto:');
            if ~ok
                return;
            end
            selectedGesture = uniqueGestures{sel};
            
            % Aggiorna il label di visualizzazione
            app.VisualizationLabel.Text = sprintf('Visualizzazione: %s - %s', selectedUser, selectedGesture);
            
            % Cerca la prima acquisizione corrispondente
            found = false;
            for i = 1:numel(acqNames)
                data = userAcquisitions.(acqNames{i});
                if strcmp(data.GestureID, selectedGesture)
                    found = true;
                    break;
                end
            end
            if ~found
                uialert(app.UIFigure, 'Nessuna acquisizione trovata per questa classe', 'Errore');
                cla(app.AccUIAxes); cla(app.GyroUIAxes);
                cla(app.MagUIAxes); cla(app.OrientationUIAxes);
                return;
            end
            
            % Visualizza i grafici (assumendo 250 campioni a 100 Hz)
            time = (0:249)/100;
            
            % Accelerometro
            plot(app.AccUIAxes, time, data.acc);
            title(app.AccUIAxes, 'Accelerometro');
            xlabel(app.AccUIAxes, 'Tempo [s]');
            ylabel(app.AccUIAxes, 'Acc [m/s^2]');
            legend(app.AccUIAxes, 'X','Y','Z');
            
            % Giroscopio
            plot(app.GyroUIAxes, time, data.gyro);
            title(app.GyroUIAxes, 'Giroscopio');
            xlabel(app.GyroUIAxes, 'Tempo [s]');
            ylabel(app.GyroUIAxes, 'Gyro [rad/s]');
            legend(app.GyroUIAxes, 'X','Y','Z');
            
            % Magnetometro
            plot(app.MagUIAxes, time, data.mag);
            title(app.MagUIAxes, 'Magnetometro');
            xlabel(app.MagUIAxes, 'Tempo [s]');
            ylabel(app.MagUIAxes, 'Mag [\muT]');
            legend(app.MagUIAxes, 'X','Y','Z');
            
            % Orientazione
            plot(app.OrientationUIAxes, time, data.orientation);
            title(app.OrientationUIAxes, 'Orientazione');
            xlabel(app.OrientationUIAxes, 'Tempo [s]');
            ylabel(app.OrientationUIAxes, 'Gradi [°]');
            legend(app.OrientationUIAxes, 'X','Y','Z');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NextButton.Enable = 'off';
            app.StopSaveButton.Enable = "off";
            app.PreparaacquisizioneeclassificaButton.Enable = 'off';
            app.AcquisisciButton.Enable = 'off';
            app.EstraiFeatureButton.Enable = "off";
            app.CambiaSelezioneButton.Enable = "off";
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            app.StatusTextArea.Value = {''};
            app.StartAcquisitionButton.Enable = 'off';  % Disabilita il tasto Start
            app.StopSaveButton.Enable = 'on';           % Abilita il tasto Stop

            % Verifica input utente
            if isempty(app.PersonIDEditField.Value) || isempty(app.SmartphoneModelEditField.Value)
                app.StatusTextArea.Value = 'Compilare tutti i campi obbligatori!';
                if ~isempty(app.smartphone) && isvalid(app.smartphone)
                    clear app.smartphone;
                end
                app.isAcquiring = false;
                return;
            end

            % Carica i dati esistenti
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            end

            % Verifica se l'ID è già presente
            while isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.StatusTextArea.Value = {'ID già in uso! Cambiare il campo Person ID'};
                pause(1); % Pausa per evitare blocchi
                newID = inputdlg('Inserisci un nuovo ID:', 'ID già in uso', [1 50]);
                if isempty(newID) % Se l'utente annulla
                    return;
                end
                app.PersonIDEditField.Value = newID{1};
            end

            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Attivazione sensori...')}];
            app.StopSaveButton.Enable = "on";
            % Resetta le variabili di stato
            app.isAcquiring = true;
            app.acquisitionInterrupted = false;
            app.globalAcquisitionCounter = 1; % Resetta contatore globale
            % Inizializza acquisizione
            try
                app.initializeAcquisition();
                app.availableSensorsStr = app.getAvailableSensors();
            catch ME
                app.StatusTextArea.Value = {'Errore nell''inizializzazione del dispositivo! Riprova.'};
                return;
            end 
            % Avvia acquisizione dal gesto selezionato
            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Avvio acquisizione dal gesto selezionato...')}];
            drawnow;
            startGestureAcquisition(app);
        end

        % Button pushed function: StopSaveButton
        function StopSaveButtonPushed(app, event)
            app.StatusTextArea.Value = {''};
            app.isAcquiring = false;
            app.acquisitionInterrupted = true;
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Acquisizione interrotta e dati salvati!'}];
            app.StartAcquisitionButton.Enable = 'on';  % Riabilita il tasto Start
            app.StopSaveButton.Enable = 'off';         % Disabilita il tasto Stop
            app.NextButton.Enable = 'off';
            % Elimina la connessione con il dispositivo per riavviare senza errori**
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                app.smartphone.Logging = 0; % Si assicura che il logging sia off
                delete(app.smartphone);
                app.smartphone = [];
                clear('mobiledev');
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.StatusTextArea.Value = {'Acquisizione in corso...'};
            app.waitForNext = false;
            app.NextButton.Enable = 'off';
        end

        % Button pushed function: CaricaModelloButton
        function CaricaModelloButtonPushed(app, event)
            % Permette di selezionare un file MAT contenente il modello pre-addestrato
            [file, path] = uigetfile('*.mat', 'Seleziona un modello pre-addestrato');
            if isequal(file, 0)
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {'Caricamento modello cancellato.'}];
                return;
            end
            modelFile = fullfile(path, file);
            try
                loadedModel = load(modelFile);
                % Controlla se il campo è 'trainedModel' o altro
                if isfield(loadedModel, 'trainedModel')
                    app.classificationModel = loadedModel.trainedModel;
                elseif isfield(loadedModel, 'exportableModel')
                    app.classificationModel = loadedModel.exportableModel;
                else
                    error('Struttura del modello non riconosciuta.');
                end
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {'Modello caricato correttamente.'}];
                app.PreparaacquisizioneeclassificaButton.Enable = 'on';
            catch ME
                app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {['Errore nel caricamento del modello', ME.message]}];
            end
        end

        % Button pushed function: PreparaacquisizioneeclassificaButton
        function PreparaacquisizioneeclassificaButtonPushed(app, event)
            % Notifica all'utente l'inizio della classificazione
            app.ClassificationStatusTextArea.Value = [app.ClassificationStatusTextArea.Value; {'Preparazione sensori per l''acquisizione...'}];
            drawnow;
            % Avvia il metodo di classificazione
            app.classifyGesture();

        end

        % Button pushed function: AcquisisciButton
        function AcquisisciButtonPushed(app, event)
            app.ClassificationStatusTextArea.Value = {'Acquisizione in corso...'};
            app.waitForAcquisisci = false;
            app.AcquisisciButton.Enable = 'off';
        end

        % Button pushed function: CaricaFileButton
        function CaricaFileButtonPushed(app, event)
            app.loadFile();
            app.CambiaSelezioneButton.Enable = "on";
        end

        % Button pushed function: CaricaFileButton_2
        function CaricaFileButton_2Pushed(app, event)
            [file, path] = uigetfile('*.mat', 'Seleziona il file signalsStructFile');
            if isequal(file,0)
                return;
            end
            filePath = fullfile(path, file);
            loadedData = load(filePath);
            if isfield(loadedData, 'signalsStructFile')
                app.signalsStruct = loadedData.signalsStructFile;
                app.FeatureStatusTextArea.Value = {'File signalsStructFile caricato correttamente.'};
                app.EstraiFeatureButton.Enable = "on";
            else
                uialert(app.UIFigure, 'Il file non contiene la variabile signalsStructFile', 'Errore');
            end
        end

        % Button pushed function: EstraiFeatureButton
        function EstraiFeatureButtonPushed(app, event)
            app.extractFeatures();
        end

        % Button pushed function: CambiaSelezioneButton
        function CambiaSelezioneButtonPushed(app, event)
            app.changeUserGesture();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisizioneDatiTab = uitab(app.TabGroup);
            app.AcquisizioneDatiTab.Title = 'Acquisizione Dati';
            app.GridLayout = uigridlayout(app.AcquisizioneDatiTab);
            app.PersonIDEditFieldLabel = uilabel(app.GridLayout);
            app.PersonIDEditFieldLabel.Text = 'Person ID';
            app.PersonIDEditField = uieditfield(app.GridLayout, 'text');
            app.PersonIDEditField.Placeholder = 'Es: P1 (P necessaria)';
            app.SmartphoneModelEditFieldLabel = uilabel(app.GridLayout);
            app.SmartphoneModelEditFieldLabel.Text = 'Smartphone Model';
            app.SmartphoneModelEditField = uieditfield(app.GridLayout, 'text');
            app.SmartphoneModelEditField.Placeholder = 'Es: "Samsung Galaxy S23"';
            app.StatusTextAreaLabel = uilabel(app.GridLayout);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.GridLayout);
            app.StatusTextArea.Placeholder = 'Qui appariranno informazioni sulle acquisizioni dei dati';
            app.StartAcquisitionButton = uibutton(app.GridLayout, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.StopSaveButton = uibutton(app.GridLayout, 'push');
            app.StopSaveButton.ButtonPushedFcn = createCallbackFcn(app, @StopSaveButtonPushed, true);
            app.StopSaveButton.Text = 'Stop & Save';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CampoobbligatorioLabel = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel.Text = {'Campo'; 'obbligatorio'};
            app.CampoobbligatorioLabel_2 = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel_2.Text = {'Campo'; 'obbligatorio'};
            app.SceglieretraleopzionidisponibiliLabel = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SceglieretraleopzionidisponibiliLabel_2 = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel_2.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SelezioneManoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneManoDropDownLabel.Text = 'Selezione Mano';
            app.SelezioneManoDropDown = uidropdown(app.GridLayout);
            app.SelezioneManoDropDown.Items = {'dx', 'sx'};
            app.SelezioneManoDropDown.Value = 'dx';
            app.SelezioneGestoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneGestoDropDownLabel.Text = 'Selezione Gesto';
            app.SelezioneGestoDropDown = uidropdown(app.GridLayout);
            app.SelezioneGestoDropDown.Items = {'Class 0', 'Class 1', 'Class 2', 'Class 3', 'Class 4', 'Class 5', 'Class 6', 'Class 7', 'Class 8', 'Class 9', 'Class 10', 'Class 11', 'Class 12', 'Class 13', 'Class 14', 'Class 15', 'Class 16', 'Class 17', 'Class 18', 'Class 19', 'Class 20', 'Class 21', 'Class 22', 'Class 23', 'Class 24'};
            app.SelezioneGestoDropDown.Value = 'Class 0';
            app.VisualizzazioneDatiTab = uitab(app.TabGroup);
            app.VisualizzazioneDatiTab.Title = 'Visualizzazione Dati';
            app.GridLayout3 = uigridlayout(app.VisualizzazioneDatiTab);
            app.MagUIAxes = uiaxes(app.GridLayout3);
            title(app.MagUIAxes, 'Title')
            xlabel(app.MagUIAxes, 'X')
            ylabel(app.MagUIAxes, 'Y')
            zlabel(app.MagUIAxes, 'Z')
            app.AccUIAxes = uiaxes(app.GridLayout3);
            title(app.AccUIAxes, 'Title')
            xlabel(app.AccUIAxes, 'X')
            ylabel(app.AccUIAxes, 'Y')
            zlabel(app.AccUIAxes, 'Z')
            app.OrientationUIAxes = uiaxes(app.GridLayout3);
            title(app.OrientationUIAxes, 'Title')
            xlabel(app.OrientationUIAxes, 'X')
            ylabel(app.OrientationUIAxes, 'Y')
            zlabel(app.OrientationUIAxes, 'Z')
            app.GyroUIAxes = uiaxes(app.GridLayout3);
            title(app.GyroUIAxes, 'Title')
            xlabel(app.GyroUIAxes, 'X')
            ylabel(app.GyroUIAxes, 'Y')
            zlabel(app.GyroUIAxes, 'Z')
            app.CaricaFileButton = uibutton(app.GridLayout3, 'push');
            app.CaricaFileButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaFileButtonPushed, true);
            app.CaricaFileButton.Text = 'Carica File';
            app.VisualizationLabel = uilabel(app.GridLayout3);
            app.VisualizationLabel.Text = '';
            app.CambiaSelezioneButton = uibutton(app.GridLayout3, 'push');
            app.CambiaSelezioneButton.ButtonPushedFcn = createCallbackFcn(app, @CambiaSelezioneButtonPushed, true);
            app.CambiaSelezioneButton.Text = 'Cambia Selezione';
            app.EstrazioneFeatureTab = uitab(app.TabGroup);
            app.EstrazioneFeatureTab.Title = 'Estrazione Feature';
            app.GridLayout4 = uigridlayout(app.EstrazioneFeatureTab);
            app.EstraiFeatureButton = uibutton(app.GridLayout4, 'push');
            app.EstraiFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @EstraiFeatureButtonPushed, true);
            app.EstraiFeatureButton.Text = 'Estrai Feature';
            app.FeatureStatusTextAreaLabel = uilabel(app.GridLayout4);
            app.FeatureStatusTextAreaLabel.Text = 'FeatureStatus';
            app.FeatureStatusTextArea = uitextarea(app.GridLayout4);
            app.CaricaFileButton_2 = uibutton(app.GridLayout4, 'push');
            app.CaricaFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @CaricaFileButton_2Pushed, true);
            app.CaricaFileButton_2.Text = 'Carica File';
            app.SelezionailfilesignalsStructFilematLabel_2 = uilabel(app.GridLayout4);
            app.SelezionailfilesignalsStructFilematLabel_2.Text = 'Seleziona il file signalsStructFile.mat';
            app.ClassificazioneGestiTab = uitab(app.TabGroup);
            app.ClassificazioneGestiTab.Title = 'Classificazione Gesti';
            app.GridLayout2 = uigridlayout(app.ClassificazioneGestiTab);
            app.CaricaModelloButton = uibutton(app.GridLayout2, 'push');
            app.CaricaModelloButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaModelloButtonPushed, true);
            app.CaricaModelloButton.Text = 'Carica Modello';
            app.PreparaacquisizioneeclassificaButton = uibutton(app.GridLayout2, 'push');
            app.PreparaacquisizioneeclassificaButton.ButtonPushedFcn = createCallbackFcn(app, @PreparaacquisizioneeclassificaButtonPushed, true);
            app.PreparaacquisizioneeclassificaButton.Text = 'Prepara acquisizione e classifica';
            app.ClassificationStatusTextAreaLabel = uilabel(app.GridLayout2);
            app.ClassificationStatusTextAreaLabel.Text = 'ClassificationStatus';
            app.ClassificationStatusTextArea = uitextarea(app.GridLayout2);
            app.AcquisisciButton = uibutton(app.GridLayout2, 'push');
            app.AcquisisciButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisisciButtonPushed, true);
            app.AcquisisciButton.Text = 'Acquisisci';
            app.InserireunmodellopreaddestratoinformatomatLabel = uilabel(app.GridLayout2);
            app.InserireunmodellopreaddestratoinformatomatLabel.Text = 'Inserire un modello pre-addestrato in formato .mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end